classdef GUIPanelDM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        zRotationSlider             matlab.ui.control.Slider
        yRotationSlider             matlab.ui.control.Slider
        xRotationSlider             matlab.ui.control.Slider
        zrSliderLabel               matlab.ui.control.Label
        yrSliderLabel               matlab.ui.control.Label
        xrSliderLabel               matlab.ui.control.Label
        zTranslationSlider          matlab.ui.control.Slider
        yTranslationSlider          matlab.ui.control.Slider
        xTranslationSlider          matlab.ui.control.Slider
        ztSliderLabel               matlab.ui.control.Label
        ytSliderLabel               matlab.ui.control.Label
        xtSliderLabel               matlab.ui.control.Label
        DobotMagicianPoseToggleControlsLabel_2  matlab.ui.control.Label
        DobotMagicianEmergencyStopLabel  matlab.ui.control.Label
        DOBOTMAGICIANGUIPANELLabel  matlab.ui.control.Label
        estopReleaseButton          matlab.ui.control.Button
        estopLamp                   matlab.ui.control.Lamp
        ESTOPLampLabel              matlab.ui.control.Label
        estopButton                 matlab.ui.control.Button
        q5Slider                    matlab.ui.control.Slider
        q5SliderLabel               matlab.ui.control.Label
        q4Slider                    matlab.ui.control.Slider
        q4SliderLabel               matlab.ui.control.Label
        q3Slider                    matlab.ui.control.Slider
        q3SliderLabel               matlab.ui.control.Label
        q2Slider                    matlab.ui.control.Slider
        q2SliderLabel               matlab.ui.control.Label
        q1Slider                    matlab.ui.control.Slider
        q1SliderLabel               matlab.ui.control.Label
        DobotMagicianJointAngleqvaluesLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Properties for robot emergency stop buttons
        estop_activated = 0;
        estop_deactivated = 0;
        q = zeros(1,5);

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial value based on Dobot Magicians current joint angles
            app.q1Slider.Value = app.q(1); 
            app.q2Slider.Value = app.q(2);
            app.q3Slider.Value = app.q(3);
            app.q4Slider.Value = app.q(4);
            app.q5Slider.Value = app.q(5);

        end

        % Button pushed function: estopButton
        function estopButtonPushed(app, event)
            app.estop_activated = true;            % flag set to indicate emergency stop for Dobot Magician has been activated
            app.estopLamp.Color = [1, 0, 0];       % Changes lamp colour to red to indicate estop has been activated
        end

        % Button pushed function: estopReleaseButton
        function estopReleaseButtonPushed(app, event)
            app.estop_deactivated = true;          % flag set to indicate emergency stop for DM has been deactivated
            app.estopLamp.Color = [1, 1, 1];       % Changes lamp colour to white to indicate DM estop has been deactivated
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            newQvalue = app.q1Slider.Value;
            app.q(1) = newQvalue;
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            newQvalue = app.q2Slider.Value;
            app.q(2) = newQvalue;
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            newQvalue = app.q3Slider.Value;
            app.q(3) = newQvalue;
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            newQvalue = app.q4Slider.Value;
            app.q(4) = newQvalue;
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            newQvalue = app.q5Slider.Value;
            app.q(5) = newQvalue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DobotMagicianJointAngleqvaluesLabel = uilabel(app.UIFigure);
            app.DobotMagicianJointAngleqvaluesLabel.Text = 'Dobot Magician Joint Angle (q values)';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.estopButton = uibutton(app.UIFigure, 'push');
            app.estopButton.ButtonPushedFcn = createCallbackFcn(app, @estopButtonPushed, true);
            app.estopButton.Text = 'ESTOP Dobot Magician';
            app.ESTOPLampLabel = uilabel(app.UIFigure);
            app.ESTOPLampLabel.Text = 'E-STOP Lamp';
            app.estopLamp = uilamp(app.UIFigure);
            app.estopReleaseButton = uibutton(app.UIFigure, 'push');
            app.estopReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @estopReleaseButtonPushed, true);
            app.estopReleaseButton.Text = 'Release Dobot Magician ESTOP';
            app.DOBOTMAGICIANGUIPANELLabel = uilabel(app.UIFigure);
            app.DOBOTMAGICIANGUIPANELLabel.Text = 'DOBOT MAGICIAN GUI PANEL';
            app.DobotMagicianEmergencyStopLabel = uilabel(app.UIFigure);
            app.DobotMagicianEmergencyStopLabel.Text = 'Dobot Magician Emergency Stop';
            app.DobotMagicianPoseToggleControlsLabel_2 = uilabel(app.UIFigure);
            app.DobotMagicianPoseToggleControlsLabel_2.Text = 'Dobot Magician Pose Toggle Controls';
            app.xtSliderLabel = uilabel(app.UIFigure);
            app.xtSliderLabel.Text = 'x  translation';
            app.ytSliderLabel = uilabel(app.UIFigure);
            app.ytSliderLabel.Text = 'y  translation';
            app.ztSliderLabel = uilabel(app.UIFigure);
            app.ztSliderLabel.Text = 'z  translation';
            app.xTranslationSlider = uislider(app.UIFigure);
            app.xTranslationSlider.Limits = [-1 1];
            app.xTranslationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.yTranslationSlider = uislider(app.UIFigure);
            app.yTranslationSlider.Limits = [-1 1];
            app.yTranslationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.zTranslationSlider = uislider(app.UIFigure);
            app.zTranslationSlider.Limits = [-1 1];
            app.zTranslationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.xrSliderLabel = uilabel(app.UIFigure);
            app.xrSliderLabel.Text = 'x rotation';
            app.yrSliderLabel = uilabel(app.UIFigure);
            app.yrSliderLabel.Text = 'y rotation';
            app.zrSliderLabel = uilabel(app.UIFigure);
            app.zrSliderLabel.Text = 'z rotation';
            app.xRotationSlider = uislider(app.UIFigure);
            app.xRotationSlider.Limits = [-1 1];
            app.xRotationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.yRotationSlider = uislider(app.UIFigure);
            app.yRotationSlider.Limits = [-1 1];
            app.yRotationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.zRotationSlider = uislider(app.UIFigure);
            app.zRotationSlider.Limits = [-1 1];
            app.zRotationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIPanelDM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end