classdef RobotsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DMLamp                         matlab.ui.control.Lamp
        DobotMagicianLampLabel         matlab.ui.control.Label
        E05LLamp                       matlab.ui.control.Lamp
        E05LLampLabel                  matlab.ui.control.Label
        estop_DM                       matlab.ui.control.Button
        releaseEstopDM                 matlab.ui.control.Button
        releaseEstopE05L               matlab.ui.control.Button
        estop_E05L                     matlab.ui.control.Button
        q6Slider_2                     matlab.ui.control.Slider
        q6Slider_2Label                matlab.ui.control.Label
        q5Slider_2                     matlab.ui.control.Slider
        q5Slider_2Label                matlab.ui.control.Label
        q4Slider_2                     matlab.ui.control.Slider
        q4Slider_2Label                matlab.ui.control.Label
        q3Slider_2                     matlab.ui.control.Slider
        q3Slider_2Label                matlab.ui.control.Label
        q2Slider_2                     matlab.ui.control.Slider
        q2Slider_2Label                matlab.ui.control.Label
        q1Slider_2                     matlab.ui.control.Slider
        q1Slider_2Label                matlab.ui.control.Label
        q5Slider                       matlab.ui.control.Slider
        q5SliderLabel                  matlab.ui.control.Label
        q4Slider                       matlab.ui.control.Slider
        q4SliderLabel                  matlab.ui.control.Label
        q3Slider                       matlab.ui.control.Slider
        q3SliderLabel                  matlab.ui.control.Label
        q2Slider                       matlab.ui.control.Slider
        q2SliderLabel                  matlab.ui.control.Label
        q1Slider                       matlab.ui.control.Slider
        q1SliderLabel                  matlab.ui.control.Label
        ElfinE05LJointAngleqvaluesLabel  matlab.ui.control.Label
        DobotMagicianJointAngleqvaluesLabel  matlab.ui.control.Label
        YawEditField_2                 matlab.ui.control.NumericEditField
        YawEditField_2Label            matlab.ui.control.Label
        PitchEditField_2               matlab.ui.control.NumericEditField
        PitchEditField_2Label          matlab.ui.control.Label
        RoleEditField_2                matlab.ui.control.NumericEditField
        RoleEditField_2Label           matlab.ui.control.Label
        ZValueEditField_2              matlab.ui.control.NumericEditField
        ZValueEditField_2Label         matlab.ui.control.Label
        YValueEditField_2              matlab.ui.control.NumericEditField
        YValueEditField_2Label         matlab.ui.control.Label
        XValueEditField_2              matlab.ui.control.NumericEditField
        XValueEditField_2Label         matlab.ui.control.Label
        ElfinE05LInformationLabel      matlab.ui.control.Label
        DobotMagicianInformationLabel  matlab.ui.control.Label
        YValueEditField                matlab.ui.control.NumericEditField
        YValueEditFieldLabel           matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        RoleEditField                  matlab.ui.control.NumericEditField
        RoleEditFieldLabel             matlab.ui.control.Label
        ZValueEditField                matlab.ui.control.NumericEditField
        ZValueEditFieldLabel           matlab.ui.control.Label
        XValueEditField                matlab.ui.control.NumericEditField
        XValueEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)

        E05_L
        DobotMagician
        
        % Properties for robot emergency stop buttons
        E05L_estop_activated = 0;
        E05L_estop_deactivated = 0;
        DM_estop_activated = 0;
        DM_estop_deactivated = 0;

        % Properties for E05L X Y Z R P Y values 

        % Properties for DM X Y Z R P Y values

        % Properties for E05L joint angles (q)

        % Properties for DM joint angles (q)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        end

        % Value changed function: XValueEditField
        function XValueEditFieldValueChanged(app, event)
            % value = app.XValueEditField.Value;
            % q = robot.model.getpos;
            % tr = robot.model.fkine(q);
            % tr(1,4) = tr(1,4) + 0.01;
            % newQ = robot.model.ikcon(tr, q);
            % robot.model.animate(newQ)
        end

        % Button pushed function: estop_E05L
        function estop_E05LButtonPushed(app, event)
            app.E05L_estop_activated = true;    % flag set to indicate emergency stop has been activated
            app.E05LLamp.Color = [1, 0, 0];     % Changes lamp colour to red to indicate estop has been activated
        end

        % Button pushed function: releaseEstopDM
        function releaseEstopDMButtonPushed(app, event)
            app.DM_estop_deactivated = true;
            app.DMLamp.Color = [1, 1, 1];
        end

        % Button pushed function: releaseEstopE05L
        function releaseEstopE05LButtonPushed(app, event)
            app.E05L_estop_deactivated = true;  % flag set to indicate emergency stop for E05L has been deactivated
            app.E05LLamp.Color = [1, 1, 1];     % Changes lamp colour to white to indicate E05L estop has been deactivated
        end

        % Button pushed function: estop_DM
        function estop_DMButtonPushed(app, event)
            app.DM_estop_activated = true;      % flag set to indicate emergency stop for Dobot Magician has been activated
            app.DMLamp.Color = [1, 0, 0];       % Changes lamp colour to red to indicate estop has been activated
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XValueEditFieldLabel = uilabel(app.UIFigure);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.XValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.XValueEditField.ValueChangedFcn = createCallbackFcn(app, @XValueEditFieldValueChanged, true);
            app.ZValueEditFieldLabel = uilabel(app.UIFigure);
            app.ZValueEditFieldLabel.Text = 'Z Value';
            app.ZValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoleEditFieldLabel = uilabel(app.UIFigure);
            app.RoleEditFieldLabel.Text = 'Role';
            app.RoleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YValueEditFieldLabel = uilabel(app.UIFigure);
            app.YValueEditFieldLabel.Text = 'Y Value';
            app.YValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DobotMagicianInformationLabel = uilabel(app.UIFigure);
            app.DobotMagicianInformationLabel.Text = 'Dobot Magician Information';
            app.ElfinE05LInformationLabel = uilabel(app.UIFigure);
            app.ElfinE05LInformationLabel.Text = 'Elfin E05-L Information';
            app.XValueEditField_2Label = uilabel(app.UIFigure);
            app.XValueEditField_2Label.Text = 'X Value';
            app.XValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YValueEditField_2Label = uilabel(app.UIFigure);
            app.YValueEditField_2Label.Text = 'Y Value';
            app.YValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZValueEditField_2Label = uilabel(app.UIFigure);
            app.ZValueEditField_2Label.Text = 'Z Value';
            app.ZValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RoleEditField_2Label = uilabel(app.UIFigure);
            app.RoleEditField_2Label.Text = 'Role';
            app.RoleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField_2Label = uilabel(app.UIFigure);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField_2Label = uilabel(app.UIFigure);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DobotMagicianJointAngleqvaluesLabel = uilabel(app.UIFigure);
            app.DobotMagicianJointAngleqvaluesLabel.Text = 'Dobot Magician Joint Angle (q values)';
            app.ElfinE05LJointAngleqvaluesLabel = uilabel(app.UIFigure);
            app.ElfinE05LJointAngleqvaluesLabel.Text = 'Elfin E05-L Joint Angle (q values)';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q1Slider_2Label = uilabel(app.UIFigure);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.UIFigure);
            app.q2Slider_2Label = uilabel(app.UIFigure);
            app.q2Slider_2Label.Text = 'q2';
            app.q2Slider_2 = uislider(app.UIFigure);
            app.q3Slider_2Label = uilabel(app.UIFigure);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.UIFigure);
            app.q4Slider_2Label = uilabel(app.UIFigure);
            app.q4Slider_2Label.Text = 'q4';
            app.q4Slider_2 = uislider(app.UIFigure);
            app.q5Slider_2Label = uilabel(app.UIFigure);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.UIFigure);
            app.q6Slider_2Label = uilabel(app.UIFigure);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.UIFigure);
            app.estop_E05L = uibutton(app.UIFigure, 'push');
            app.estop_E05L.ButtonPushedFcn = createCallbackFcn(app, @estop_E05LButtonPushed, true);
            app.estop_E05L.Text = 'E05L ESTOP';
            app.releaseEstopE05L = uibutton(app.UIFigure, 'push');
            app.releaseEstopE05L.ButtonPushedFcn = createCallbackFcn(app, @releaseEstopE05LButtonPushed, true);
            app.releaseEstopE05L.Text = 'Release E05L ESTOP';
            app.releaseEstopDM = uibutton(app.UIFigure, 'push');
            app.releaseEstopDM.ButtonPushedFcn = createCallbackFcn(app, @releaseEstopDMButtonPushed, true);
            app.releaseEstopDM.Text = 'Release Dobot Magician ESTOP';
            app.estop_DM = uibutton(app.UIFigure, 'push');
            app.estop_DM.ButtonPushedFcn = createCallbackFcn(app, @estop_DMButtonPushed, true);
            app.estop_DM.Text = 'ESTOP Dobot Magician';
            app.E05LLampLabel = uilabel(app.UIFigure);
            app.E05LLampLabel.Text = 'E05L Lamp';
            app.E05LLamp = uilamp(app.UIFigure);
            app.DobotMagicianLampLabel = uilabel(app.UIFigure);
            app.DobotMagicianLampLabel.Text = 'Dobot Magician';
            app.DMLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end